classdef IRmode < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SetupforIRalignmentmodePanel    matlab.ui.container.Panel
        SetB0B1exposureButton           matlab.ui.control.Button
        BlockHeNeButton                 matlab.ui.control.Button
        UnblockEPSShutterButton         matlab.ui.control.Button
        CloseIrisButton                 matlab.ui.control.Button
        SetHWPButton                    matlab.ui.control.Button
        PerformIRalignmentPanel         matlab.ui.container.Panel
        StartIRalignmentButton          matlab.ui.control.Button
        StopIRalignmentButton           matlab.ui.control.Button
        ReturnsettingstoHeNealignmentmodePanel  matlab.ui.container.Panel
        SetB0B1exposureButton_2         matlab.ui.control.Button
        UnblockHeNeButton               matlab.ui.control.Button
        BlockEPSShutterButton           matlab.ui.control.Button
        SetHWPButton_2                  matlab.ui.control.Button
        OpenIrisButton                  matlab.ui.control.Button
        LogTextAreaLabel                matlab.ui.control.Label
        LogTextArea                     matlab.ui.control.TextArea
        GrabIRreferenceparametersPanel  matlab.ui.container.Panel
        GrabIRReferencesButton          matlab.ui.control.Button
        AlignmentStatusLampLabel        matlab.ui.control.Label
        AlignmentStatusLamp             matlab.ui.control.Lamp
        HeNestatusLampLabel             matlab.ui.control.Label
        HeNestatusLamp                  matlab.ui.control.Lamp
        EPSshutterLampLabel             matlab.ui.control.Label
        EPSshutterLamp                  matlab.ui.control.Lamp
        IRGainEditFieldLabel            matlab.ui.control.Label
        IRGainEditField                 matlab.ui.control.NumericEditField
        ClearLogButton                  matlab.ui.control.Button
        MisalignmentTolerancepixelsEditFieldLabel  matlab.ui.control.Label
        MisalignmentTolerancepixelsEditField  matlab.ui.control.NumericEditField
        CurrentirissizeLabel            matlab.ui.control.Label
        IrisSizeL                       matlab.ui.control.Label
        HWProtLabel                     matlab.ui.control.Label
        HWProtL                         matlab.ui.control.Label
    end

    
    properties (Access = public)
        config = loadIRPyConfig();
        align
        feedbackExitPV = 'SIOC:SYS1:ML01:AO183';
        maxMisalignmentTolerancePV = 'SIOC:SYS1:ML01:AO188';
        disableTimestampCheckForCamerasPV = 'SIOC:SYS1:ML01:AO187';
        fitMethod = 2;
        maxMisalignmentTolerance;
        MPANearFarmaxMisalignmentTolerance;
        disableTimestampCheckForCameras;
        
        HeNeBlockTRAPV = 'XPS:LA20:LS24:M3';
        HeNeBlockOut = 8;
        HeNeBlockIn = 10;
        
        IrisPV = 'XPS:LA20:LS24:M4';
        HWProtPV = 'XPS:LA20:LS24:M2';
        
        EPSShutterPV = 'DO:LA20:10:Bo1';
        EPSShutterBlocked = 'High';
        EPSShutterOpen = 'Low';
        
        initExposure = [];
        
    end
    % Helper functions
    methods (Access = public)
        
        function checkBool = performPreAlignmentChecks(app)
            % PERFORMPREALIGNMENTCHECKS Warns the user if settings
            % necessary for alignment have not been set.
            
            % Check to make sure a section has been selected for alignment
            checkBool = 1;
            checkBool = app.align.checkReferences(checkBool);
        end
        
        % #################################################################
        % #
        % # Functions that implement GUI actions
        % #
        % #################################################################
        function initiateShutterPanel(app)
            app.setHeNeStatus();
            app.setEPSShutterStatus();
        end
        
        function setHeNeStatus(app)
            HeNeVal = lcaGetSmart([app.HeNeBlockTRAPV,'.RBV']);
            if abs(HeNeVal - app.HeNeBlockOut) < 0.001
                app.HeNestatusLamp.Color = [0, 1, 0];
                app.HeNestatusLamp.Tooltip = 'HeNe block is out, HeNe is being sent';
            elseif  abs(HeNeVal - app.HeNeBlockIn) < 0.001
                app.HeNestatusLamp.Color = [1, 0, 0];
                app.HeNestatusLamp.Tooltip = 'HeNe block is in, HeNe is being blocked';
            else
                app.HeNestatusLamp.Color = [1, 1, 0];
                app.HeNestatusLamp.Tooltip = 'Status unknown';
            end
        end
        
        function flipEPSShutter(app)
            ShutterVal = lcaGetSmart(app.EPSShutterPV);
            
            if strcmp(ShutterVal, app.EPSShutterBlocked) 
                lcaPutSmart(app.EPSShutterPV, app.EPSShutterOpen);
            elseif strcmp(ShutterVal, app.EPSShutterOpen)
                lcaPutSmart(app.EPSShutterPV, app.EPSShutterBlocked);
            end
            
            app.setEPSShutterStatus();
        end
        
        function setEPSShutterStatus(app)
            ShutterVal = lcaGetSmart(app.EPSShutterPV);
            
            if strcmp(ShutterVal,'High')
                app.EPSshutterLamp.Color = [1, 0, 0];
                app.EPSshutterLamp.Tooltip = 'EPS shutter is in';
                %app.BlockUnblockButton_2.Text = 'Unblock';
            elseif strcmp(ShutterVal,'Low')
                app.EPSshutterLamp.Color = [0, 1, 0];
                app.EPSshutterLamp.Tooltip = 'EPS shutter is out';
                %app.BlockUnblockButton_2.Text = 'Block';
            end

        end
        
        function moveStepperMotor(app, motorPV, motorName, target)
            
            lcaPutSmart(motorPV, target);
            motorVal = lcaGetSmart([motorPV,'.RBV']);
            app.appendMessage(['Moving motor: ', motorName]);
            
            while ~(abs(motorVal - target) < 0.01)
                motorVal = lcaGetSmart([motorPV,'.RBV']);
                app.updateStatusLamps();
            end
            
            app.appendMessage(['Finished moving motor: ', motorName]);
            
        end
        
        function appendMessage(app, msg)
            % APPENDMESSAGE Appends a message to the log textArea and
            % redraws the text area. Automatically removes lines from the
            % log after 500 lines.
            %
            % Args:
            %     msg: Message to append to the top of the log area.
            
            N = numel(app.LogTextArea.Value);
            % Clear the log after this many lines
            cutoff = 500;
            if N > cutoff
                N = cutoff;
            end
            app.LogTextArea.Value = ...
                [msg, ...
                app.LogTextArea.Value(1:N)'] ;
            drawnow;
        end
        
        function updateStatusLamps(app)
            app.setHeNeStatus();
            app.setEPSShutterStatus();
            app.setIrisStatus();
            app.setHWPStatus();
            
            drawnow();
        end
        
        function setIrisStatus(app)
            ISize = lcaGetSmart([app.IrisPV,'.RBV']);
            app.IrisSizeL.Text = sprintf('%2.1f',-ISize);
        end
        function setHWPStatus(app)
            HWProt = lcaGetSmart([app.HWProtPV,'.RBV']);
            app.HWProtL.Text = sprintf('%3.1f',HWProt);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % STARTUPFCN Loads necessary data for the app to run
            
            app.align = AlignerCommon(app);
            % Perform other setup work
            app.align.clearReferences();
            app.config = loadCalibrationMatrices(app.config);
            app.initiateShutterPanel();
            
            sections = fieldnames(app.config);
            section = app.config.(sections{1});
            app.IRGainEditField.Value = lcaGetSmart(section.gainPV);
            % Get current exposures so we know what to go back to
            sections = fieldnames(app.config);
            section = app.config.(sections{1});
            cameras = fieldnames(section.cameras);
            for j=1:numel(cameras)
                camera = section.cameras.(cameras{j});
                app.initExposure(j) = lcaGetSmart(strcat(camera.cameraPV, ':AcquireTime'));
            end
            app.appendMessage('Exposure time set for IR');
            app.maxMisalignmentTolerance = lcaGetSmart(app.maxMisalignmentTolerancePV);
            app.updateStatusLamps();
        end

        % Button pushed function: BlockHeNeButton
        function BlockHeNeButtonPushed(app, event)
                        % Blocks/unblocks the HeNe
            
            HeNeVal = lcaGetSmart(app.HeNeBlockTRAPV);
            
            app.HeNestatusLamp.Color = [1, 1, 0];
            app.HeNestatusLamp.Tooltip = 'Status unknown';
            drawnow();
            
            if abs(HeNeVal - app.HeNeBlockIn) < 0.001
                target = app.HeNeBlockOut;
            else
                target = app.HeNeBlockIn;
            end
                     
            app.moveStepperMotor(app.HeNeBlockTRAPV, 'HeNe block', target);
            app.LogTextArea.Value = ['He Ne Blocked...',...
            app.LogTextArea.Value(:)'];
            app.setHeNeStatus();
            drawnow();
            

        end

        % Button pushed function: SetB0B1exposureButton
        function SetB0B1exposureButtonPushed(app, event)
            sections = fieldnames(app.config);
            section = app.config.(sections{1});
            cameras = fieldnames(section.cameras);
            for j=1:numel(cameras)
                camera = section.cameras.(cameras{j});
                lcaPutSmart(strcat(camera.cameraPV, ':AcquireTime'), camera.irExposure);
            end
            app.appendMessage('Exposure time set for IR');
            
        end

        % Button pushed function: GrabIRReferencesButton
        function GrabIRReferencesButtonPushed(app, event)
            % GRABIRREFERENCESBUTTONPUSHED Clears the existing reference
            % parameters and grabs new ones from the cameras.
            
            app.align.clearReferences()
            app.appendMessage('Grabbing Reference Laser Parameters...');
            app.config = defineFeedbackSetpoint(app.config, app.fitMethod);
            app.appendMessage('Grabbed Reference Laser Parameters');
        end

        % Button pushed function: StartIRalignmentButton
        function StartIRalignmentButtonPushed(app, event)
            % STARTALIGNMENTBUTTON Loops through the sections, checks if
            % each section is valid, if it is aligns them
            
            lcaPutSmart(app.feedbackExitPV, 0);% Set exit PV to zero
            checkBool = performPreAlignmentChecks(app);
            if ~checkBool;return;end% Exit alignment if pre-checks are bad
            app.appendMessage('Starting Auto Alignment');
            app.AlignmentStatusLamp.Color = 'Green';
            drawnow()
            
            sections = fieldnames(app.config);
            section = app.config.(sections{1});
            
            % Only align while the PV says we can
            while lcaGetSmart(app.feedbackExitPV) == 0% Start the feedback
                % This is in the loop so the user can change the sections while the autoaligner is running
                section.gain = lcaGetSmart(section.gainPV);
                lcaPutSmart(section.feedbackOnPV, 1)

                app.maxMisalignmentTolerance = lcaGetSmart(app.maxMisalignmentTolerancePV);% Update max misalignment tolerance
                app.disableTimestampCheckForCameras = lcaGetSmart(app.disableTimestampCheckForCamerasPV);% Update timestamp check
                
                if any([isnan(app.maxMisalignmentTolerance), ...
                        ~isreal(app.maxMisalignmentTolerance), ...
                        ~isnumeric(app.maxMisalignmentTolerance)])
                    app.appendMessage('Invalid value for max misalignment tolerance. Skipping alignment');
                    continue
                end
                
                % Kill the auto-aligner at the current section rather than waiting for all the sections
                if lcaGetSmart(app.feedbackExitPV) == 0
                    if app.align.evaluateExitCondition(section)
                        continue;
                    else % Run the feedback
                        app.appendMessage(['Aligning on ', section.name]);
                        app.align.alignLaserToSetpoint(section);
                    end
                else
                    if lcaGetSmart(app.feedbackExitPV)
                        continue
                    end %Makes sure you don't get stuck on pause and exit PVs = 1
                end
                drawnow()
            end
        end

        % Button pushed function: StopIRalignmentButton
        function StopIRalignmentButtonPushed(app, event)
            lcaPutSmart(app.feedbackExitPV,1)
            app.appendMessage('Auto-Alignment Stopped');
            app.AlignmentStatusLamp.Color = 'Red';
        end

        % Button pushed function: UnblockHeNeButton
        function UnblockHeNeButtonPushed(app, event)
                        % Blocks/unblocks the HeNe
            
            HeNeVal = lcaGetSmart(app.HeNeBlockTRAPV);
            
            app.HeNestatusLamp.Color = [1, 1, 0];
            app.HeNestatusLamp.Tooltip = 'Status unknown';
            drawnow();
            
            if abs(HeNeVal - app.HeNeBlockIn) < 0.001
                target = app.HeNeBlockOut;
            else
                target = app.HeNeBlockIn;
            end
                     
            app.moveStepperMotor(app.HeNeBlockTRAPV, 'HeNe block', target);
            app.LogTextArea.Value = ['He Ne Blocked...',...
            app.LogTextArea.Value(:)'];
            app.setHeNeStatus
            drawnow();
            
        end

        % Button pushed function: SetB0B1exposureButton_2
        function SetB0B1exposureButton_2Pushed(app, event)
            sections = fieldnames(app.config);
            section = app.config.(sections{1});
            cameras = fieldnames(section.cameras);
            for j=1:numel(cameras)
                camera = section.cameras.(cameras{j});
                lcaPutSmart(strcat(camera.cameraPV, ':AcquireTime'), app.initExposure(j));
            end
            app.appendMessage('Exposure time set for HeNe');
        end

        % Value changed function: IRGainEditField
        function IRGainEditFieldValueChanged(app, event)
            value = app.IRGainEditField.Value;
            lcaPutSmart(app.IRgainPV,value);
        end

        % Button pushed function: UnblockEPSShutterButton
        function UnblockEPSShutterButtonPushed(app, event)
            app.flipEPSShutter();
        end

        % Button pushed function: BlockEPSShutterButton
        function BlockEPSShutterButtonPushed(app, event)
            app.flipEPSShutter();
        end

        % Button pushed function: ClearLogButton
        function ClearLogButtonPushed(app, event)
             app.LogTextArea.Value =  ' ';
        end

        % Value changed function: 
        % MisalignmentTolerancepixelsEditField
        function MisalignmentTolerancepixelsEditFieldValueChanged(app, event)
            value = app.MisalignmentTolerancepixelsEditField.Value;
            lcaPutSmart(app.maxMisalignmentTolerancePV,value);
        end

        % Button pushed function: OpenIrisButton
        function OpenIrisButtonPushed(app, event)
            % Opens iris to 40 mm aperture
            
            app.moveStepperMotor(app.IrisPV, 'Iris size', -40);
            app.setIrisStatus();
        end

        % Button pushed function: CloseIrisButton
        function CloseIrisButtonPushed(app, event)
            % Closes iris to 5 mm apeture
            
            app.moveStepperMotor(app.IrisPV, 'Iris size', -5);
            app.setIrisStatus();
        end

        % Button pushed function: SetHWPButton
        function SetHWPButtonPushed(app, event)
            % Rotates the waveplate to 65 degrees
            
            app.moveStepperMotor(app.HWProtPV, 'HWP', 65);
            app.setHWPStatus();
        end

        % Button pushed function: SetHWPButton_2
        function SetHWPButton_2Pushed(app, event)
            % Rotates the waveplate to 50 degrees
            
            app.moveStepperMotor(app.HWProtPV, 'HWP', 50);
            app.setHWPStatus();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SetupforIRalignmentmodePanel = uipanel(app.UIFigure);
            app.SetupforIRalignmentmodePanel.Title = '1. Set-up for IR alignment mode';
            app.SetB0B1exposureButton = uibutton(app.SetupforIRalignmentmodePanel, 'push');
            app.SetB0B1exposureButton.ButtonPushedFcn = createCallbackFcn(app, @SetB0B1exposureButtonPushed, true);
            app.SetB0B1exposureButton.Text = {'Set B0 B1 '; 'exposure'};
            app.BlockHeNeButton = uibutton(app.SetupforIRalignmentmodePanel, 'push');
            app.BlockHeNeButton.ButtonPushedFcn = createCallbackFcn(app, @BlockHeNeButtonPushed, true);
            app.BlockHeNeButton.Text = {'Block '; 'HeNe'};
            app.UnblockEPSShutterButton = uibutton(app.SetupforIRalignmentmodePanel, 'push');
            app.UnblockEPSShutterButton.ButtonPushedFcn = createCallbackFcn(app, @UnblockEPSShutterButtonPushed, true);
            app.UnblockEPSShutterButton.Text = {'Unblock '; 'EPS Shutter'};
            app.CloseIrisButton = uibutton(app.SetupforIRalignmentmodePanel, 'push');
            app.CloseIrisButton.ButtonPushedFcn = createCallbackFcn(app, @CloseIrisButtonPushed, true);
            app.CloseIrisButton.Text = {'Close'; 'Iris'};
            app.SetHWPButton = uibutton(app.SetupforIRalignmentmodePanel, 'push');
            app.SetHWPButton.ButtonPushedFcn = createCallbackFcn(app, @SetHWPButtonPushed, true);
            app.SetHWPButton.Text = {'Set '; 'HWP'};
            app.PerformIRalignmentPanel = uipanel(app.UIFigure);
            app.PerformIRalignmentPanel.Title = '3. Perform IR alignment';
            app.StartIRalignmentButton = uibutton(app.PerformIRalignmentPanel, 'push');
            app.StartIRalignmentButton.ButtonPushedFcn = createCallbackFcn(app, @StartIRalignmentButtonPushed, true);
            app.StartIRalignmentButton.Text = 'Start IR alignment';
            app.StopIRalignmentButton = uibutton(app.PerformIRalignmentPanel, 'push');
            app.StopIRalignmentButton.ButtonPushedFcn = createCallbackFcn(app, @StopIRalignmentButtonPushed, true);
            app.StopIRalignmentButton.Text = 'Stop IR alignment';
            app.ReturnsettingstoHeNealignmentmodePanel = uipanel(app.UIFigure);
            app.ReturnsettingstoHeNealignmentmodePanel.Title = '4. Return settings to HeNe alignment mode';
            app.SetB0B1exposureButton_2 = uibutton(app.ReturnsettingstoHeNealignmentmodePanel, 'push');
            app.SetB0B1exposureButton_2.ButtonPushedFcn = createCallbackFcn(app, @SetB0B1exposureButton_2Pushed, true);
            app.SetB0B1exposureButton_2.Text = {'Set B0 B1 '; 'exposure'};
            app.UnblockHeNeButton = uibutton(app.ReturnsettingstoHeNealignmentmodePanel, 'push');
            app.UnblockHeNeButton.ButtonPushedFcn = createCallbackFcn(app, @UnblockHeNeButtonPushed, true);
            app.UnblockHeNeButton.Text = {'Unblock '; 'HeNe'};
            app.BlockEPSShutterButton = uibutton(app.ReturnsettingstoHeNealignmentmodePanel, 'push');
            app.BlockEPSShutterButton.ButtonPushedFcn = createCallbackFcn(app, @BlockEPSShutterButtonPushed, true);
            app.BlockEPSShutterButton.Text = {'Block '; 'EPS '; 'Shutter'};
            app.SetHWPButton_2 = uibutton(app.ReturnsettingstoHeNealignmentmodePanel, 'push');
            app.SetHWPButton_2.ButtonPushedFcn = createCallbackFcn(app, @SetHWPButton_2Pushed, true);
            app.SetHWPButton_2.Text = {'Set '; 'HWP'};
            app.OpenIrisButton = uibutton(app.ReturnsettingstoHeNealignmentmodePanel, 'push');
            app.OpenIrisButton.ButtonPushedFcn = createCallbackFcn(app, @OpenIrisButtonPushed, true);
            app.OpenIrisButton.Text = {'Open'; 'Iris'};
            app.LogTextAreaLabel = uilabel(app.UIFigure);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.UIFigure);
            app.GrabIRreferenceparametersPanel = uipanel(app.UIFigure);
            app.GrabIRreferenceparametersPanel.Title = '2. Grab IR reference parameters';
            app.GrabIRReferencesButton = uibutton(app.GrabIRreferenceparametersPanel, 'push');
            app.GrabIRReferencesButton.ButtonPushedFcn = createCallbackFcn(app, @GrabIRReferencesButtonPushed, true);
            app.GrabIRReferencesButton.Text = 'Grab IR References';
            app.AlignmentStatusLampLabel = uilabel(app.UIFigure);
            app.AlignmentStatusLampLabel.Text = 'Alignment Status';
            app.AlignmentStatusLamp = uilamp(app.UIFigure);
            app.HeNestatusLampLabel = uilabel(app.UIFigure);
            app.HeNestatusLampLabel.Text = 'HeNe status';
            app.HeNestatusLamp = uilamp(app.UIFigure);
            app.EPSshutterLampLabel = uilabel(app.UIFigure);
            app.EPSshutterLampLabel.Text = 'EPS shutter';
            app.EPSshutterLamp = uilamp(app.UIFigure);
            app.IRGainEditFieldLabel = uilabel(app.UIFigure);
            app.IRGainEditFieldLabel.Text = 'IR Gain';
            app.IRGainEditField = uieditfield(app.UIFigure, 'numeric');
            app.IRGainEditField.ValueChangedFcn = createCallbackFcn(app, @IRGainEditFieldValueChanged, true);
            app.ClearLogButton = uibutton(app.UIFigure, 'push');
            app.ClearLogButton.ButtonPushedFcn = createCallbackFcn(app, @ClearLogButtonPushed, true);
            app.ClearLogButton.Text = 'Clear Log';
            app.MisalignmentTolerancepixelsEditFieldLabel = uilabel(app.UIFigure);
            app.MisalignmentTolerancepixelsEditFieldLabel.Text = 'Misalignment Tolerance [pixels]';
            app.MisalignmentTolerancepixelsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MisalignmentTolerancepixelsEditField.ValueChangedFcn = createCallbackFcn(app, @MisalignmentTolerancepixelsEditFieldValueChanged, true);
            app.CurrentirissizeLabel = uilabel(app.UIFigure);
            app.CurrentirissizeLabel.Text = 'Current iris size:';
            app.IrisSizeL = uilabel(app.UIFigure);
            app.IrisSizeL.Text = 'init';
            app.HWProtLabel = uilabel(app.UIFigure);
            app.HWProtLabel.Text = 'HWP rot:';
            app.HWProtL = uilabel(app.UIFigure);
            app.HWProtL.Text = 'init';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IRmode

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end