classdef expertSettings_F2_LAA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UITable   matlab.ui.control.Table
    end

    
    properties (Access = private)
        sectionNames = {'Pulse Picker and Regen Out','Preamp Near and Far','MPA Near and Far',...
            'HeNe Near and Far','B0 and B1','B2 and B3','B4','B5','B6'} % Description
        gainPVs = {'SIOC:SYS1:ML01:AO190','SIOC:SYS1:ML01:AO191','SIOC:SYS1:ML01:AO199',...
            'SIOC:SYS1:ML01:AO192','SIOC:SYS1:ML01:AO193',...
            'SIOC:SYS1:ML01:AO194','SIOC:SYS1:ML01:AO195',...
            'SIOC:SYS1:ML01:AO196','SIOC:SYS1:ML01:AO197'}
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            gainValues=lcaGetSmart(app.gainPVs);
            app.UITable.Data = table(app.sectionNames',gainValues);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            disp(double(table2array(app.UITable.Data(:,2))))
            newGainValues = double(table2array(app.UITable.Data(:,2)));
            lcaPutSmart(app.gainPVs,newGainValues);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Laser Cameras'; 'Gain'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = expertSettings_F2_LAA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end