classdef s20LaserTargetPositionsTable < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UITable            matlab.ui.control.Table
        TargetseLogButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
                     camerapvs = {'CAMR:LT20:0001','CAMR:LT20:0002','CAMR:LT20:0003',...
    'CAMR:LT20:0004','CAMR:LT20:0009','CAMR:LT20:0010','CAMR:LT20:0101','CAMR:LT20:0102',...
            'CAMR:LT20:0103','CAMR:LT20:0104','CAMR:LT20:0105','CAMR:LT20:0106','CAMR:LT20:0107'};
                umPerPixel = 1;
                cameraNames = {};
                statsBool = 1;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            s20LaserTargetPositions = importdata('/home/fphysics/cemma/S20Laser/S20LaserAlignmentFeedback/s20LaserTargetPositionswMPA.mat');
            strs = {s20LaserTargetPositions.cameraPV};
                for ij = 1:length(app.camerapvs)
                    ind=find(ismember(strs,app.camerapvs{ij}));
                    name = s20LaserTargetPositions(ind).cameraName;
                    app.cameraNames{ij} = name{:};
                    bp(ij,1)=s20LaserTargetPositions(ind).XmeanTarget;
                    bp(ij,2)=s20LaserTargetPositions(ind).YmeanTarget;
                end
                bp(:,1:2) = round(bp(:,1:2),4,'significant');
                 t = table(app.cameraNames',bp(:,1),bp(:,2));
                    app.UITable.Data = t;
            
            
        end

        % Button pushed function: TargetseLogButton
        function TargetseLogButtonPushed(app, event)
            addpath('/usr/local/facet/tools/matlabTNG/common/')
            info_str = sprintf(['| Camera Name | X mean Target [pix] | Y mean Target [pix]  \n']);
            for i = 1:height(app.UITable.Data)
             mat_line   = sprintf(['| ' app.cameraNames{i} ' | '  num2str(app.UITable.Data{i,2}) ' | '...
             num2str(app.UITable.Data{i,3}) '\n']);
           info_str = [info_str mat_line];
            end
          
            info_str = sprintf([info_str '\n' '\n']);
            
            figure(99);
            ax = axes();
            set(ax, 'Visible', 'off');
            set(gcf,'Position',[10 10 10 10]);
            util_printLog2020(99,'title','S20 Laser Target Position Values',...
            'author','S20 Laser Target Position GUI','text',info_str);
            clf(99), close(99);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Camera Name'; 'Target X Mean [pix]'; 'Target Y Mean [pix]'};
            app.UITable.RowName = {};
            app.TargetseLogButton = uibutton(app.UIFigure, 'push');
            app.TargetseLogButton.ButtonPushedFcn = createCallbackFcn(app, @TargetseLogButtonPushed, true);
            app.TargetseLogButton.Text = 'Targets -> eLog';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = s20LaserTargetPositionsTable

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end