classdef statsTable < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UITable               matlab.ui.control.Table
        ShowLiveStatsButton   matlab.ui.control.Button
        PauseLiveStatsButton  matlab.ui.control.Button
        StatseLogButton       matlab.ui.control.Button
    end

    
    properties (Access = public)
                camerapvs = {'CAMR:LT20:0001';'CAMR:LT20:0002';'CAMR:LT20:0003';'CAMR:LT20:0004';...
        'CAMR:LT20:0005',;'CAMR:LT20:0006';'CAMR:LT20:0007';'CAMR:LT20:0007'}; % Description
                umPerPixel = 1;
                statsBool = 1;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
                for ij = 1:length(app.camerapvs)
                    a=lcaGet([app.camerapvs{ij},':NAME']);
                    cameraNames{ij} = a{:};
                    [bp(ij,:),img]=GrabLaserBeamPropertiesV3(app,ij);
                end
                bp(:,1:4) = round(bp(:,1:4));
                 t = table(cameraNames',bp(:,1),bp(:,2),bp(:,3),bp(:,4),bp(:,5));
                    app.UITable.Data = t;
            
            
        end

        % Button pushed function: ShowLiveStatsButton
        function ShowLiveStatsButtonPushed(app, event)
                       app.statsBool = 1;
               while app.statsBool
               
                for ij = 1:length(app.camerapvs)
                                a=lcaGet([app.camerapvs{ij},':NAME']);
                    cameraNames{ij} = a{:};
                [bp(ij,:),img]=GrabLaserBeamPropertiesV3(app,ij);
                end
                 bp(:,1:4) = round(bp(:,1:4));
                 t = table(cameraNames',bp(:,1),bp(:,2),bp(:,3),bp(:,4),bp(:,5));
                app.UITable.Data = t;
                pause(0.2)
               end
        end

        % Button pushed function: PauseLiveStatsButton
        function PauseLiveStatsButtonPushed(app, event)
            app.statsBool = 0;
        end

        % Button pushed function: StatseLogButton
        function StatseLogButtonPushed(app, event)
            fs = figure;
            fs.Position = [1 1000 800 400];
            fs.PaperSize = [12 11];
            uit = uitable(fs,'Data',table2cell(app.UITable.Data));
            uit.ColumnName = {app.UITable.ColumnName{:}};
            uit.RowName = [];
            print(fs, '-dpsc2', ['-P','physics-facetlog']);  
            close(fs)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Camera Name'; 'X Mean [pix]'; 'Y Mean [pix]'; 'X RMS [pix]'; 'Y RMS [pix]'; 'Sum Cts [MCts]'};
            app.UITable.RowName = {};
            app.ShowLiveStatsButton = uibutton(app.UIFigure, 'push');
            app.ShowLiveStatsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowLiveStatsButtonPushed, true);
            app.ShowLiveStatsButton.Text = 'Show Live Stats';
            app.PauseLiveStatsButton = uibutton(app.UIFigure, 'push');
            app.PauseLiveStatsButton.ButtonPushedFcn = createCallbackFcn(app, @PauseLiveStatsButtonPushed, true);
            app.PauseLiveStatsButton.Text = 'Pause Live Stats';
            app.StatseLogButton = uibutton(app.UIFigure, 'push');
            app.StatseLogButton.ButtonPushedFcn = createCallbackFcn(app, @StatseLogButtonPushed, true);
            app.StatseLogButton.Text = 'Stats -> eLog';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = statsTable

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end