classdef F2_SliceBPMDispersion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NotchJawCollimatorControlPanel  matlab.ui.Figure
        ZLocation                       matlab.ui.control.NumericEditField
        ZLocationEditFieldLabel         matlab.ui.control.Label
        MoveCollimatorsTogetherPanel    matlab.ui.container.Panel
        ShotsperBuffer                  matlab.ui.control.NumericEditField
        ShotsBufferLabel                matlab.ui.control.Label
        ScanNotesLabel                  matlab.ui.control.Label
        ScanFinishedLabel               matlab.ui.control.Label
        ScaninProgressLabel             matlab.ui.control.Label
        ABORTButton                     matlab.ui.control.Button
        ScanStartButton                 matlab.ui.control.Button
        NumSteps                        matlab.ui.control.NumericEditField
        StepsEditFieldLabel             matlab.ui.control.Label
        EndPos                          matlab.ui.control.NumericEditField
        StartPosLabel_3                 matlab.ui.control.Label
        StartPos                        matlab.ui.control.NumericEditField
        StartPosLabel                   matlab.ui.control.Label
        CollimatorControlsPanel         matlab.ui.container.Panel
        RetractAll                      matlab.ui.control.Button
        JawHLSLamp                      matlab.ui.control.Lamp
        JawLLSLamp                      matlab.ui.control.Lamp
        NotchHLSLamp                    matlab.ui.control.Lamp
        Label_2                         matlab.ui.control.Label
        NotchLLSLamp                    matlab.ui.control.Lamp
        Label                           matlab.ui.control.Label
        NotchHome                       matlab.ui.control.Button
        YawRead                         matlab.ui.control.NumericEditField
        CurrentlyEditFieldLabel_5       matlab.ui.control.Label
        YawSet                          matlab.ui.control.NumericEditField
        SettoEditFieldLabel_5           matlab.ui.control.Label
        YawLabel                        matlab.ui.control.Label
        JawLeftRead                     matlab.ui.control.NumericEditField
        CurrentlyEditFieldLabel_3       matlab.ui.control.Label
        JawLeftSet                      matlab.ui.control.NumericEditField
        SettoEditFieldLabel_3           matlab.ui.control.Label
        JawCollimatorLabel              matlab.ui.control.Label
        NotchRead                       matlab.ui.control.NumericEditField
        CurrentlyEditFieldLabel         matlab.ui.control.Label
        NotchCollimatorLabel            matlab.ui.control.Label
        NotchSet                        matlab.ui.control.NumericEditField
        SettoEditFieldLabel             matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        SYAGColorIntensity              matlab.ui.control.Slider
        ColorIntensitySlider_2Label     matlab.ui.control.Label
        DTOTRColorIntensity             matlab.ui.control.Slider
        ColorIntensitySliderLabel       matlab.ui.control.Label
        DTOTRStart                      matlab.ui.control.Button
        SYAGStart                       matlab.ui.control.Button
        SYAGStop                        matlab.ui.control.Button
        DTOTRStop                       matlab.ui.control.Button
        SYAG                            matlab.ui.control.UIAxes
        DTOTR                           matlab.ui.control.UIAxes
        tmitPlot                        matlab.ui.control.UIAxes
        ypPlot                          matlab.ui.control.UIAxes
        xpPlot                          matlab.ui.control.UIAxes
        yPlot                           matlab.ui.control.UIAxes
        xPlot                           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        xROI = [];  % creates property (x Region Of Interest) as an empty array
        yROI = [];  % creates property (y Region Of Interest) as an empty arra
        DTOTRtimer
        SYAGtimer 
        aobj; % object for support class
    end


    methods (Access = private)
        function startTimers(app,~)   % Starts SYAG and DTOTR timers
            if isempty(app.DTOTRtimer) || ~isvalid(app.DTOTRtimer)
                app.DTOTRtimer = timer('ExecutionMode','fixedRate','Period',0.1,'TimerFcn',@(~,~) updateDTOTR(app));
            end
            if strcmp(app.DTOTRtimer.Running, 'off')
                start(app.DTOTRtimer);
            end
            if isempty(app.SYAGtimer) || ~isvalid(app.SYAGtimer)
                app.SYAGtimer = timer('ExecutionMode','fixedRate','Period',0.1,'TimerFcn',@(~,~) updateSYAG(app), 'StopFcn',@(~,~)delete(timerfind('Name','SYAGtimer')));
            end
            if strcmp(app.SYAGtimer.Running, 'off')
                start(app.SYAGtimer); %add start timer functions
            end
        end
        function updateDTOTR(app,~) %Grabs image and adjust Roi for DTOTR
            dtotr_data = profmon_grab('CAMR:LI20:107'); %grabs image 
            app.xROI = dtotr_data.roiX:dtotr_data.roiX+dtotr_data.roiXN-1; 
            app.yROI = dtotr_data.roiY:dtotr_data.roiY+dtotr_data.roiYN-1;  % Sets axis range for camera
            dtotr_img = (dtotr_data.img);
            imagesc(app.DTOTR, app.xROI, app.yROI, dtotr_img); %displays image in app.DTOTR axis
            axis(app.DTOTR, 'tight')
            drawnow;
        end
        function updateSYAG(app,~)  %Grabs image and adjust ROI for SYAG
            syag_data = profmon_grab('CAMR:LI20:100'); %grabs image from camera
            app.xROI = syag_data.roiX:syag_data.roiX+syag_data.roiXN-1; 
            app.yROI = syag_data.roiY:syag_data.roiY+syag_data.roiYN-1;  % Sets axis range for camera
            syag_img = (syag_data.img);
            imagesc(app.SYAG, app.xROI, app.yROI, syag_img); %displays image in app.SYAG axis
            axis(app.SYAG, 'tight')
            drawnow;
        end
     end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.aobj = F2_SliceBPMApp(app); % links to .m file
            app.startTimers(); %Camera timers      
        end

        % Button pushed function: DTOTRStart
        function DTOTRStartButtonPushed(app, event)
            if isempty(app.DTOTRtimer) || ~isvalid(app.DTOTRtimer)
               app.DTOTRtimer = timer('ExecutionMode','fixedRate','Period',0.1,'TimerFcn',@(~,~) updateDTOTR(app));
            % call DTOTR function every time
            end
            if strcmp(app.DTOTRtimer.Running,'off')
               start(app.DTOTRtimer)             % starts timer if it isnt already running
            end
        end

        % Button pushed function: DTOTRStop
        function DTOTRStopButtonPushed(app, event)
             if ~isempty(app.DTOTRtimer) && isvalid(app.DTOTRtimer) 
                stop(app.DTOTRtimer) %stop timer if it running
            end
        end

        % Button pushed function: SYAGStart
        function SYAGStartButtonPushed(app, event)
            if isempty(app.SYAGtimer) || ~isvalid(app.SYAGtimer)
               app.SYAGtimer = timer('ExecutionMode','fixedRate','Period',0.1,'TimerFcn',@(~,~) updateSYAG(app));
               %calls SYAG function for the follwing two timer if
               %statements
            end
            if strcmp(app.SYAGtimer.Running,'off')
               start(app.SYAGtimer) %starts timer for SYAG if not already on
            end
        end

        % Button pushed function: SYAGStop
        function SYAGStopButtonPushed(app, event)
            if ~isempty(app.SYAGtimer) && isvalid(app.SYAGtimer)
                stop(app.SYAGtimer) %stops timer of SYAG if not already off
            end
        end

        % Value changed function: DTOTRColorIntensity
        function DTOTRColorIntensityValueChanged(app, event)
            value = app.DTOTRColorIntensity.Value;
            MaxPixel = 2^value-1;
            clim(app.DTOTR, [0 MaxPixel]);
        end

        % Value changed function: SYAGColorIntensity
        function SYAGColorIntensityValueChanged(app, event)
            value = app.SYAGColorIntensity.Value;
            MaxPixel = 2^value-1;
            clim(app.SYAG, [0 MaxPixel]);
        end

        % Button pushed function: ScanStartButton
        function ScanStartButtonPushed(app, event)
            app.aobj.scanCols();
        end

        % Button pushed function: ABORTButton
        function ABORTButtonPushed(app, event)
            app.aobj.abort();
        end

        % Button pushed function: NotchHome
        function NotchHomeButtonPushed(app, event)
            app.aobj.notchHome();
        end

        % Close request function: NotchJawCollimatorControlPanel
        function NotchJawCollimatorControlPanelCloseRequest(app, event)
           % make al stop flags true
           app.aobj.stopflag = true;

           %Stop timers
            if ~isempty(app.DTOTRtimer) && isvalid(app.DTOTRtimer) 
                if strcmp(app.DTOTRtimer.Running, 'on')
                    stop(app.DTOTRtimer) %stop timer if it running
                end
            end
            if ~isempty(app.SYAGtimer) && isvalid(app.SYAGtimer) 
                if strcmp(app.SYAGtimer.Running, 'on')
                    stop(app.SYAGtimer) %stop timer if it running
                end
            end
            
            %deletes the app
            delete(app)        
        end

        % Button pushed function: RetractAll
        function RetractAllButtonPushed(app, event)
            app.aobj.retractCols();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NotchJawCollimatorControlPanel = uifigure('Visible', 'off');
            app.NotchJawCollimatorControlPanel.Name = 'Notch/Jaw Collimator Control Panel';
            app.NotchJawCollimatorControlPanel.CloseRequestFcn = createCallbackFcn(app, @NotchJawCollimatorControlPanelCloseRequest, true);
            app.xPlot = uiaxes(app.NotchJawCollimatorControlPanel);
            xlabel(app.xPlot, 'Slice Energy [GeV]')
            ylabel(app.xPlot, 'x-x_0 [µm]')
            zlabel(app.xPlot, 'Z')
            app.yPlot = uiaxes(app.NotchJawCollimatorControlPanel);
            xlabel(app.yPlot, 'Slice Energy [GeV]')
            ylabel(app.yPlot, 'y-y_0 [µm]')
            zlabel(app.yPlot, 'Z')
            app.xpPlot = uiaxes(app.NotchJawCollimatorControlPanel);
            xlabel(app.xpPlot, 'Slice Energy [GeV]')
            ylabel(app.xpPlot, 'x''-x''_0 [µrad]')
            zlabel(app.xpPlot, 'Z')
            app.ypPlot = uiaxes(app.NotchJawCollimatorControlPanel);
            xlabel(app.ypPlot, 'Slice Energy [GeV]')
            ylabel(app.ypPlot, 'y''-y''_0 [µrad]')
            zlabel(app.ypPlot, 'Z')
            app.tmitPlot = uiaxes(app.NotchJawCollimatorControlPanel);
            xlabel(app.tmitPlot, 'Slice Energy [GeV]')
            ylabel(app.tmitPlot, 'Slice Charge [nC]')
            zlabel(app.tmitPlot, 'Z')
            app.Panel = uipanel(app.NotchJawCollimatorControlPanel);
            app.DTOTR = uiaxes(app.Panel);
            title(app.DTOTR, 'DTOTR')
            xlabel(app.DTOTR, 'X')
            ylabel(app.DTOTR, 'Y')
            zlabel(app.DTOTR, 'Z')
            app.SYAG = uiaxes(app.Panel);
            title(app.SYAG, 'SYAG')
            xlabel(app.SYAG, 'X')
            ylabel(app.SYAG, 'Y')
            zlabel(app.SYAG, 'Z')
            app.DTOTRStop = uibutton(app.Panel, 'push');
            app.DTOTRStop.ButtonPushedFcn = createCallbackFcn(app, @DTOTRStopButtonPushed, true);
            app.DTOTRStop.Text = 'Stop';
            app.SYAGStop = uibutton(app.Panel, 'push');
            app.SYAGStop.ButtonPushedFcn = createCallbackFcn(app, @SYAGStopButtonPushed, true);
            app.SYAGStop.Text = 'Stop';
            app.SYAGStart = uibutton(app.Panel, 'push');
            app.SYAGStart.ButtonPushedFcn = createCallbackFcn(app, @SYAGStartButtonPushed, true);
            app.SYAGStart.Text = 'Start';
            app.DTOTRStart = uibutton(app.Panel, 'push');
            app.DTOTRStart.ButtonPushedFcn = createCallbackFcn(app, @DTOTRStartButtonPushed, true);
            app.DTOTRStart.Text = 'Start';
            app.ColorIntensitySliderLabel = uilabel(app.Panel);
            app.ColorIntensitySliderLabel.Text = 'Color Intensity';
            app.DTOTRColorIntensity = uislider(app.Panel);
            app.DTOTRColorIntensity.Limits = [8 16];
            app.DTOTRColorIntensity.MajorTicks = [];
            app.DTOTRColorIntensity.ValueChangedFcn = createCallbackFcn(app, @DTOTRColorIntensityValueChanged, true);
            app.DTOTRColorIntensity.MinorTicks = [];
            app.DTOTRColorIntensity.Value = 12;
            app.ColorIntensitySlider_2Label = uilabel(app.Panel);
            app.ColorIntensitySlider_2Label.Text = 'Color Intensity';
            app.SYAGColorIntensity = uislider(app.Panel);
            app.SYAGColorIntensity.Limits = [1 16];
            app.SYAGColorIntensity.MajorTicks = [];
            app.SYAGColorIntensity.ValueChangedFcn = createCallbackFcn(app, @SYAGColorIntensityValueChanged, true);
            app.SYAGColorIntensity.MinorTicks = [];
            app.SYAGColorIntensity.Value = 8;
            app.CollimatorControlsPanel = uipanel(app.NotchJawCollimatorControlPanel);
            app.CollimatorControlsPanel.Title = 'Collimator Controls';
            app.SettoEditFieldLabel = uilabel(app.CollimatorControlsPanel);
            app.SettoEditFieldLabel.Text = 'Set to:';
            app.NotchSet = uieditfield(app.CollimatorControlsPanel, 'numeric');
            app.NotchCollimatorLabel = uilabel(app.CollimatorControlsPanel);
            app.NotchCollimatorLabel.Text = 'Notch Collimator';
            app.CurrentlyEditFieldLabel = uilabel(app.CollimatorControlsPanel);
            app.CurrentlyEditFieldLabel.Text = 'Currently:';
            app.NotchRead = uieditfield(app.CollimatorControlsPanel, 'numeric');
            app.NotchRead.Editable = 'off';
            app.JawCollimatorLabel = uilabel(app.CollimatorControlsPanel);
            app.JawCollimatorLabel.Text = 'Jaw Collimator';
            app.SettoEditFieldLabel_3 = uilabel(app.CollimatorControlsPanel);
            app.SettoEditFieldLabel_3.Text = 'Set to:';
            app.JawLeftSet = uieditfield(app.CollimatorControlsPanel, 'numeric');
            app.CurrentlyEditFieldLabel_3 = uilabel(app.CollimatorControlsPanel);
            app.CurrentlyEditFieldLabel_3.Text = 'Currently:';
            app.JawLeftRead = uieditfield(app.CollimatorControlsPanel, 'numeric');
            app.JawLeftRead.Editable = 'off';
            app.YawLabel = uilabel(app.CollimatorControlsPanel);
            app.YawLabel.Text = 'Yaw';
            app.SettoEditFieldLabel_5 = uilabel(app.CollimatorControlsPanel);
            app.SettoEditFieldLabel_5.Text = 'Set to:';
            app.YawSet = uieditfield(app.CollimatorControlsPanel, 'numeric');
            app.CurrentlyEditFieldLabel_5 = uilabel(app.CollimatorControlsPanel);
            app.CurrentlyEditFieldLabel_5.Text = 'Currently:';
            app.YawRead = uieditfield(app.CollimatorControlsPanel, 'numeric');
            app.YawRead.Editable = 'off';
            app.NotchHome = uibutton(app.CollimatorControlsPanel, 'push');
            app.NotchHome.ButtonPushedFcn = createCallbackFcn(app, @NotchHomeButtonPushed, true);
            app.NotchHome.Text = 'Home';
            app.Label = uilabel(app.CollimatorControlsPanel);
            app.Label.Text = '';
            app.NotchLLSLamp = uilamp(app.CollimatorControlsPanel);
            app.Label_2 = uilabel(app.CollimatorControlsPanel);
            app.Label_2.Text = '';
            app.NotchHLSLamp = uilamp(app.CollimatorControlsPanel);
            app.JawLLSLamp = uilamp(app.CollimatorControlsPanel);
            app.JawHLSLamp = uilamp(app.CollimatorControlsPanel);
            app.RetractAll = uibutton(app.CollimatorControlsPanel, 'push');
            app.RetractAll.ButtonPushedFcn = createCallbackFcn(app, @RetractAllButtonPushed, true);
            app.RetractAll.Text = 'Retract All';
            app.MoveCollimatorsTogetherPanel = uipanel(app.NotchJawCollimatorControlPanel);
            app.MoveCollimatorsTogetherPanel.Title = 'Collimator Scan';
            app.StartPosLabel = uilabel(app.MoveCollimatorsTogetherPanel);
            app.StartPosLabel.Text = 'Start Position:';
            app.StartPos = uieditfield(app.MoveCollimatorsTogetherPanel, 'numeric');
            app.StartPosLabel_3 = uilabel(app.MoveCollimatorsTogetherPanel);
            app.StartPosLabel_3.Text = 'End Position:';
            app.EndPos = uieditfield(app.MoveCollimatorsTogetherPanel, 'numeric');
            app.StepsEditFieldLabel = uilabel(app.MoveCollimatorsTogetherPanel);
            app.StepsEditFieldLabel.Text = '# Steps';
            app.NumSteps = uieditfield(app.MoveCollimatorsTogetherPanel, 'numeric');
            app.ScanStartButton = uibutton(app.MoveCollimatorsTogetherPanel, 'push');
            app.ScanStartButton.ButtonPushedFcn = createCallbackFcn(app, @ScanStartButtonPushed, true);
            app.ScanStartButton.Text = 'Start';
            app.ABORTButton = uibutton(app.MoveCollimatorsTogetherPanel, 'push');
            app.ABORTButton.ButtonPushedFcn = createCallbackFcn(app, @ABORTButtonPushed, true);
            app.ABORTButton.Text = 'ABORT';
            app.ScaninProgressLabel = uilabel(app.MoveCollimatorsTogetherPanel);
            app.ScaninProgressLabel.Visible = 'off';
            app.ScaninProgressLabel.Text = '**Scan in Progress**';
            app.ScanFinishedLabel = uilabel(app.MoveCollimatorsTogetherPanel);
            app.ScanFinishedLabel.Visible = 'off';
            app.ScanFinishedLabel.Text = '**Scan Finished**';
            app.ScanNotesLabel = uilabel(app.MoveCollimatorsTogetherPanel);
            app.ScanNotesLabel.Text = 'Set Start and End positions for the Jaw Collimator';
            app.ShotsBufferLabel = uilabel(app.MoveCollimatorsTogetherPanel);
            app.ShotsBufferLabel.Text = 'Shots/Buffer:';
            app.ShotsperBuffer = uieditfield(app.MoveCollimatorsTogetherPanel, 'numeric');
            app.ShotsperBuffer.Value = 100;
            app.ZLocationEditFieldLabel = uilabel(app.NotchJawCollimatorControlPanel);
            app.ZLocationEditFieldLabel.Text = 'Z Location:';
            app.ZLocation = uieditfield(app.NotchJawCollimatorControlPanel, 'numeric');
            app.ZLocation.Value = 1993.91;
            % Show the figure after all components are created
            app.NotchJawCollimatorControlPanel.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = F2_SliceBPMDispersion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NotchJawCollimatorControlPanel)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NotchJawCollimatorControlPanel)
        end
    end
end